classdef paroject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        SelectfileButton          matlab.ui.control.Button
        FileEditFieldLabel        matlab.ui.control.Label
        FileEditField             matlab.ui.control.EditField
        DrawButton                matlab.ui.control.Button
        Label                     matlab.ui.control.Label
        StartpointEditFieldLabel  matlab.ui.control.Label
        StartpointEditField       matlab.ui.control.EditField
    end

    
    methods (Access = private)
        
        function my_plot_matrix(app, my_matrix, start_point)
            
            for indx = 1:size(my_matrix, 1)
                hold(app.UIAxes, 'on');
                app.my_plot_row(my_matrix(indx,:), start_point);
                hold(app.UIAxes, 'off');
            end
            
        end
        
        function my_plot_row(app, my_row, start_point)
            [my_row_to_draw, start_val] = app.my_transform_row(my_row, start_point);
            my_xvals = start_val:1:length(my_row_to_draw) - 1 + start_val;
            plot(app.UIAxes, my_xvals, my_row_to_draw);             
        end
        
        function [my_row_to_draw, my_start_point] = my_transform_row(~,my_row, my_start_point_text)
             my_row_to_draw = my_row;
            
            for indx = 1:length(my_row_to_draw)
                if(my_row_to_draw(indx) ~= 0)
                    break;
                end
                my_row_to_draw(indx) = nan;
            end
            
            my_start_point = str2double(my_start_point_text);
            if ~isnan(my_start_point)
                my_row_to_draw = my_row_to_draw(my_start_point:end);
            else
                my_start_point = 0;
            end
        end
        
        function my_drop_plot(app)
            cla(app.UIAxes);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            [file, path] = uigetfile('*.xls;*.xlsx;*.csv');
            if ~isequal(file,0)
                app.FileEditField.Value = fullfile(path, file);
            end
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
             my_fullPath = app.FileEditField.Value;
            
             if ~exist(my_fullPath, 'file')
                 app.Label.Text = "Enter correct file name";
                 return;
             else
                 app.Label.Text = "";
                 app.UIAxes.Title.String = "";
             end
             
             my_matrix = readmatrix(my_fullPath, 'NumHeaderLines', 0);
             app.my_drop_plot();
             
             [~,name, ext] = fileparts(my_fullPath);
             app.UIAxes.Title.String = strcat(name, ext);
             
             if(size(my_matrix, 1) > 0)
                 app.my_plot_matrix(my_matrix, app.StartpointEditField.Value);
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SelectfileButton = uibutton(app.UIFigure, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.FileEditFieldLabel = uilabel(app.UIFigure);
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.UIFigure, 'text');
            app.FileEditField.Tooltip = {'Absolute path'};
            app.DrawButton = uibutton(app.UIFigure, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.StartpointEditFieldLabel = uilabel(app.UIFigure);
            app.StartpointEditFieldLabel.Text = 'Start point';
            app.StartpointEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = paroject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end