classdef task_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FirstNameTextAreaLabel     matlab.ui.control.Label
        FirstNameTextArea          matlab.ui.control.TextArea
        LastNameTextAreaLabel      matlab.ui.control.Label
        LastNameTextArea           matlab.ui.control.TextArea
        PatronymicTextAreaLabel    matlab.ui.control.Label
        PatronymicTextArea         matlab.ui.control.TextArea
        UITable                    matlab.ui.control.Table
        TargetscoreEditFieldLabel  matlab.ui.control.Label
        TargetscoreEditField       matlab.ui.control.NumericEditField
        ClickmeButton              matlab.ui.control.Button
        ResultTextAreaLabel        matlab.ui.control.Label
        ResultTextArea             matlab.ui.control.TextArea
        AddrowButton               matlab.ui.control.Button
        RemoverowButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.ColumnFormat = {'char', 'numeric'};
        end

        % Button pushed function: ClickmeButton
        function ClickmeButtonPushed(app, event)
            score_points = app.UITable.Data(:,2);
            score_sum = sum(cell2mat(score_points));
            if score_sum >= app.TargetscoreEditField.Value
                app.ResultTextArea.Value = "You passed";
            else
                app.ResultTextArea.Value = "Sorry, you failed";
            end
        end

        % Button pushed function: AddrowButton
        function AddrowButtonPushed(app, event)
            oldData = app.UITable.get('Data');
            newData = [oldData; {'Subject', '0'}];
            app.UITable.set('Data', newData);
        end

        % Button pushed function: RemoverowButton
        function RemoverowButtonPushed(app, event)
            table_size = size(app.UITable.Data, 1);
            if table_size > 0
                app.UITable.Data(table_size, :) = [];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FirstNameTextAreaLabel = uilabel(app.UIFigure);
            app.FirstNameTextAreaLabel.Text = {'First Name'; ''};
            app.FirstNameTextArea = uitextarea(app.UIFigure);
            app.LastNameTextAreaLabel = uilabel(app.UIFigure);
            app.LastNameTextAreaLabel.Text = 'Last Name';
            app.LastNameTextArea = uitextarea(app.UIFigure);
            app.PatronymicTextAreaLabel = uilabel(app.UIFigure);
            app.PatronymicTextAreaLabel.Text = 'Patronymic';
            app.PatronymicTextArea = uitextarea(app.UIFigure);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Subject'; 'Score'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false true];
            app.UITable.ColumnEditable = true;
            app.TargetscoreEditFieldLabel = uilabel(app.UIFigure);
            app.TargetscoreEditFieldLabel.Text = 'Target score';
            app.TargetscoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.ClickmeButton = uibutton(app.UIFigure, 'push');
            app.ClickmeButton.ButtonPushedFcn = createCallbackFcn(app, @ClickmeButtonPushed, true);
            app.ClickmeButton.Text = 'Click me';
            app.ResultTextAreaLabel = uilabel(app.UIFigure);
            app.ResultTextAreaLabel.Text = 'Result';
            app.ResultTextArea = uitextarea(app.UIFigure);
            app.ResultTextArea.Editable = 'off';
            app.ResultTextArea.Tooltip = {'Computed field'};
            app.ResultTextArea.Value = {'We don''t know yet'};
            app.AddrowButton = uibutton(app.UIFigure, 'push');
            app.AddrowButton.ButtonPushedFcn = createCallbackFcn(app, @AddrowButtonPushed, true);
            app.AddrowButton.Text = 'Add row';
            app.RemoverowButton = uibutton(app.UIFigure, 'push');
            app.RemoverowButton.ButtonPushedFcn = createCallbackFcn(app, @RemoverowButtonPushed, true);
            app.RemoverowButton.Text = 'Remove row';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = task_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end