classdef task_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FunctionsButtonGroup  matlab.ui.container.ButtonGroup
        ysinxCheckBox         matlab.ui.control.CheckBox
        ycosxCheckBox         matlab.ui.control.CheckBox
        DrawButton            matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             app.UIAxes.XTick = 0:5:4*pi;
%             app.UIAxes.XTickLabel = 0:5:4*pi;
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            x_vals = 0:0.01:4*pi;
            if app.ycosxCheckBox.Value 
                hold(app.UIAxes,'on');
                y_vals = cos(x_vals);
                plot(app.UIAxes, x_vals, y_vals);
                hold(app.UIAxes,'off');
            end
            if app.ysinxCheckBox.Value
                hold(app.UIAxes,'on');
                y_vals = sin(x_vals);
                plot(app.UIAxes, x_vals, y_vals);
                hold(app.UIAxes,'off');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FunctionsButtonGroup = uibuttongroup(app.UIFigure);
            app.FunctionsButtonGroup.Title = 'Functions';
            app.ysinxCheckBox = uicheckbox(app.FunctionsButtonGroup);
            app.ysinxCheckBox.Text = 'y=sin(x)';
            app.ycosxCheckBox = uicheckbox(app.FunctionsButtonGroup);
            app.ycosxCheckBox.Text = 'y=cos(x)';
            app.DrawButton = uibutton(app.UIFigure, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = task_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end