classdef task_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        TabGroup      matlab.ui.container.TabGroup
        FirstTab      matlab.ui.container.Tab
        UIAxes        matlab.ui.control.UIAxes
        DrawButton    matlab.ui.control.StateButton
        UIAxes_2      matlab.ui.control.UIAxes
        SecondTab     matlab.ui.container.Tab
        DrawButton_2  matlab.ui.control.StateButton
    end

    methods (Access = private)
        
        function [] = set_tab_content(app, my_button, my_axes, my_func, my_linspace)
            value = my_button.Value;
            if value
                my_button.Text = "Clear";
                app.draw_plot(my_axes, my_func, my_linspace);
            else
                my_button.Text = "Draw";
                my_axes.cla();
            end
        end
        
        function [] = draw_plot(~, my_axes, my_func, my_linspace)
            y_vals = my_func(my_linspace);
            plot(my_axes,my_linspace, y_vals);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Value changed function: DrawButton
        function DrawButtonValueChanged(app, event)
            my_func = @(x) exp(-x.^2);
            app.set_tab_content(app.DrawButton, app.UIAxes, my_func, -2:0.2:2);
        end

        % Value changed function: DrawButton_2
        function DrawButton_2ValueChanged(app, event)
            my_func = @(x) sin(x);
            app.set_tab_content(app.DrawButton_2, app.UIAxes_2, my_func, 0:0.1:4*pi);    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FirstTab = uitab(app.TabGroup);
            app.FirstTab.Tooltip = {''};
            app.FirstTab.Title = 'First';
            app.UIAxes = uiaxes(app.FirstTab);
            title(app.UIAxes, 'exp(-x^2)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DrawButton = uibutton(app.FirstTab, 'state');
            app.DrawButton.ValueChangedFcn = createCallbackFcn(app, @DrawButtonValueChanged, true);
            app.DrawButton.Tooltip = {'Click me'};
            app.DrawButton.Text = 'Draw';
            app.SecondTab = uitab(app.TabGroup);
            app.SecondTab.Title = 'Second';
            app.UIAxes_2 = uiaxes(app.SecondTab);
            title(app.UIAxes_2, 'sin(x)')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.DrawButton_2 = uibutton(app.SecondTab, 'state');
            app.DrawButton_2.ValueChangedFcn = createCallbackFcn(app, @DrawButton_2ValueChanged, true);
            app.DrawButton_2.Tooltip = {'Click me'};
            app.DrawButton_2.Text = 'Draw';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = task_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end